classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        importdataxlsButton  matlab.ui.control.Button
    end


    properties (Access = private)
        Data % Gögn úr excel skjali
    end

    methods (Access = public)
        % Reiknar út meanReturn, StdDev og Covariance Matrix fyrir object Data
        function [meanReturn,StdDev,covMatrix] = assets(obj)
            meanReturn = (mean(obj.Data)')/100;
            StdDev = (std(obj.Data))/100;
            covMatrix = (cov(obj.Data))/100;
        end
    end


    methods (Access = private)

        % Button pushed function: importdataxlsButton
        function importdataxlsButtonPushed(app, event)
            fileName = uigetfile('*.xlsx');
            [num,txt,raw] = xlsread(fileName);
            app.Data = num;
            
            [meanReturn,StdDev,covMatrix] = assets(app);
            disp(meanReturn);
            disp(StdDev);
            disp(covMatrix);
            
            %disp(meanReturn)
            %disp(stdDev(app))
            %disp(covMatrix(app))
            
            %app.result.Value = j;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Portfolio management')
            xlabel(app.UIAxes, 'Std.Dev.')
            ylabel(app.UIAxes, 'Returns')
            app.importdataxlsButton = uibutton(app.UIFigure, 'push');
            app.importdataxlsButton.ButtonPushedFcn = createCallbackFcn(app, @importdataxlsButtonPushed, true);
            app.importdataxlsButton.Text = 'import data .xls';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end